classdef game < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        Panel         matlab.ui.container.Panel
        right         matlab.ui.control.Button
        up            matlab.ui.control.Button
        left          matlab.ui.control.Button
        down          matlab.ui.control.Button
        Panel2        matlab.ui.container.Panel
        GridLayout    matlab.ui.container.GridLayout
        Button        matlab.ui.control.StateButton
        Button2       matlab.ui.control.StateButton
        Button3       matlab.ui.control.StateButton
        Button4       matlab.ui.control.StateButton
        Button5       matlab.ui.control.StateButton
        Button7       matlab.ui.control.StateButton
        Button8       matlab.ui.control.StateButton
        Button9       matlab.ui.control.StateButton
        Button10      matlab.ui.control.StateButton
        Button6       matlab.ui.control.StateButton
        UndoButton    matlab.ui.control.Button
        SuccessLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
%         Chess % Description
        currentchoose = [];
        currentno;
        Engine;
        historychess = [];
        historyno = [];
        historymove = [];
        
    end
    
    methods (Access = private)
        
        function clickevent(app, Button, no)
            app.Panel.Visible = Button.Value;
            if(~isempty(app.currentchoose))
                if(app.currentchoose(1) ~= Button)
                    app.currentchoose(1).Value = 0;
                    app.currentchoose = [Button];
                    app.currentno = no;
                else
                    app.currentchoose = [];
                    app.currentno = -1;
                end
            else
                app.currentchoose = [Button];
                app.currentno = no;
            end
            
            
        end
        
        function suc = undo(app, no, dir, button)
            if dir == 1
                [suc, ~, ~] = app.Engine.moveChess(no, 'down');
                button.Layout.Row = button.Layout.Row + 1;
            elseif dir == 2
                [suc, ~, ~] = app.Engine.moveChess(no, 'up');
                button.Layout.Row = button.Layout.Row - 1;
            elseif dir == 3
                [suc, ~, ~] = app.Engine.moveChess(no, 'right');
                button.Layout.Column = button.Layout.Column + 1;
            elseif dir == 4
                [suc, ~, ~] = app.Engine.moveChess(no, 'left');
                button.Layout.Column = button.Layout.Column - 1;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Button
        function ButtonValueChanged(app, event)
            app.clickevent(app.Button, 1);
        end

        % Value changed function: Button2
        function Button2ValueChanged(app, event)
            app.clickevent(app.Button2, 2);
        end

        % Value changed function: Button3
        function Button3ValueChanged(app, event)
            app.clickevent(app.Button3, 3);
        end

        % Value changed function: Button4
        function Button4ValueChanged(app, event)
            app.clickevent(app.Button4, 4);
        end

        % Value changed function: Button5
        function Button5ValueChanged(app, event)
            app.clickevent(app.Button5, 5);
        end

        % Value changed function: Button6
        function Button6ValueChanged(app, event)
            app.clickevent(app.Button6, 6);
        end

        % Value changed function: Button7
        function Button7ValueChanged(app, event)
            app.clickevent(app.Button7, 7);
        end

        % Value changed function: Button8
        function Button8ValueChanged(app, event)
            app.clickevent(app.Button8, 8);
        end

        % Value changed function: Button9
        function Button9ValueChanged(app, event)
            app.clickevent(app.Button9, 9);
        end

        % Value changed function: Button10
        function Button10ValueChanged(app, event)
            app.clickevent(app.Button10, 10);
        end

        % Button pushed function: up
        function upButtonPushed(app, event)
            a = app.Engine.moveChess(app.currentno, 'up');
            if a
                app.currentchoose(1).Layout.Row = app.currentchoose(1).Layout.Row - 1;
                app.historyno(end + 1) = app.currentno;
                app.historymove(end + 1) = 1;
                app.historychess = [app.historychess; app.currentchoose(1)];
                if app.Engine.verifySuccess()
                    app.SuccessLabel.Visible = true;
                end
            end
            
        end

        % Button pushed function: right
        function rightButtonPushed(app, event)
            a = app.Engine.moveChess(app.currentno, 'right');
            if a
                app.currentchoose(1).Layout.Column = app.currentchoose(1).Layout.Column + 1;
                app.historyno(end + 1) = app.currentno;
                app.historymove(end + 1) = 4;
                app.historychess = [app.historychess; app.currentchoose(1)];
                if app.Engine.verifySuccess()
                    app.SuccessLabel.Visible = true;
                end
            end
        end

        % Button pushed function: down
        function downButtonPushed(app, event)
            a = app.Engine.moveChess(app.currentno, 'down');
            if a
                app.currentchoose(1).Layout.Row = app.currentchoose(1).Layout.Row + 1;
                app.historyno(end + 1) = app.currentno;
                app.historymove(end + 1) = 2;
                app.historychess = [app.historychess; app.currentchoose(1)];
                if app.Engine.verifySuccess()
                    app.SuccessLabel.Visible = true;
                end
            end

        end

        % Button pushed function: left
        function leftButtonPushed(app, event)
            a = app.Engine.moveChess(app.currentno, 'left');
            if a
                app.currentchoose(1).Layout.Column = app.currentchoose(1).Layout.Column - 1;
                app.historyno(end + 1) = app.currentno;
                app.historymove(end + 1) = 3;
                app.historychess = [app.historychess; app.currentchoose(1)];
                if app.Engine.verifySuccess()
                    app.SuccessLabel.Visible = true;
                end
            end
        end

        % Button pushed function: UndoButton
        function UndoButtonPushed(app, event)
            if ~isempty(app.historychess())
                app.undo(app.historyno(end), app.historymove(end), app.historychess(end));
                app.historychess(end) = [];
                app.historymove(end) = [];
                app.historyno(end) = [];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.right = uibutton(app.Panel, 'push');
            app.right.ButtonPushedFcn = createCallbackFcn(app, @rightButtonPushed, true);
            app.right.Text = {'→'; ''};
            app.up = uibutton(app.Panel, 'push');
            app.up.ButtonPushedFcn = createCallbackFcn(app, @upButtonPushed, true);
            app.up.Text = '↑';
            app.left = uibutton(app.Panel, 'push');
            app.left.ButtonPushedFcn = createCallbackFcn(app, @leftButtonPushed, true);
            app.left.Text = '←';
            app.down = uibutton(app.Panel, 'push');
            app.down.ButtonPushedFcn = createCallbackFcn(app, @downButtonPushed, true);
            app.down.Text = '↓';
            app.Panel2 = uipanel(app.UIFigure);
            app.Panel2.Title = 'Panel2';
            app.GridLayout = uigridlayout(app.Panel2);
            app.Button = uibutton(app.GridLayout, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Text = 'Button';
            app.Button2 = uibutton(app.GridLayout, 'state');
            app.Button2.ValueChangedFcn = createCallbackFcn(app, @Button2ValueChanged, true);
            app.Button2.Text = 'Button2';
            app.Button3 = uibutton(app.GridLayout, 'state');
            app.Button3.ValueChangedFcn = createCallbackFcn(app, @Button3ValueChanged, true);
            app.Button3.Text = 'Button3';
            app.Button4 = uibutton(app.GridLayout, 'state');
            app.Button4.ValueChangedFcn = createCallbackFcn(app, @Button4ValueChanged, true);
            app.Button4.Text = 'Button4';
            app.Button5 = uibutton(app.GridLayout, 'state');
            app.Button5.ValueChangedFcn = createCallbackFcn(app, @Button5ValueChanged, true);
            app.Button5.Text = 'Button5';
            app.Button7 = uibutton(app.GridLayout, 'state');
            app.Button7.ValueChangedFcn = createCallbackFcn(app, @Button7ValueChanged, true);
            app.Button7.Text = 'Button7';
            app.Button8 = uibutton(app.GridLayout, 'state');
            app.Button8.ValueChangedFcn = createCallbackFcn(app, @Button8ValueChanged, true);
            app.Button8.Text = 'Button8';
            app.Button9 = uibutton(app.GridLayout, 'state');
            app.Button9.ValueChangedFcn = createCallbackFcn(app, @Button9ValueChanged, true);
            app.Button9.Text = 'Button9';
            app.Button10 = uibutton(app.GridLayout, 'state');
            app.Button10.ValueChangedFcn = createCallbackFcn(app, @Button10ValueChanged, true);
            app.Button10.Text = 'Button10';
            app.Button6 = uibutton(app.GridLayout, 'state');
            app.Button6.ValueChangedFcn = createCallbackFcn(app, @Button6ValueChanged, true);
            app.Button6.Text = {'Button6'; ''};
            app.UndoButton = uibutton(app.UIFigure, 'push');
            app.UndoButton.ButtonPushedFcn = createCallbackFcn(app, @UndoButtonPushed, true);
            app.UndoButton.Text = {'Undo'; ''};
            app.SuccessLabel = uilabel(app.UIFigure);
            app.SuccessLabel.Visible = 'off';
            app.SuccessLabel.Text = 'Success';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = game

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end